classdef SignalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TypeEditField         matlab.ui.control.EditField
        TypeEditFieldLabel    matlab.ui.control.Label
        SignalSettingsButton  matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        WaveStruct = struct('WaveType','Constant',...   % Wave type
                            'OffsetPressure',500,...    % in hPa
                            'Amplitude',100,...         % in hPa
                            'Period',1,...              % in s
                            'Frequency',1,...           % in Hz
                            'SampleRate',4,...          % in Hz
                            'Repeat',0,...
                            'HalfPulse',0);
        GlobalHandles                                   % Struct to store handles
    end
    
    methods (Access = private)
        % Function for the Popup Type ValueChangedFcn callback
        function PopupTypeDropDownChanged(app)
            app.WaveStruct.WaveType = app.GlobalHandles.PopupTypeDropDown.Value;
            app.TypeEditField.Value = app.GlobalHandles.PopupTypeDropDown.Value;
            PlotPreview(app)
        end
        
        % Function for the Offset Pressure ValueChangedFcn callback
        function OffsetPressureValueChanged(app)
            app.WaveStruct.OffsetPressure = app.GlobalHandles.OffsetPressureEditField.Value;
            PlotPreview(app)
        end
        
        % Function for the Amplitude ValueChangedFcn callback
        function AmplitudeValueChanged(app)
            app.WaveStruct.Amplitude = app.GlobalHandles.AmplitudeEditField.Value;
            PlotPreview(app)
        end
        
        % Function for the Period ValueChangedFcn callback
        function PeriodValueChanged(app)
            app.WaveStruct.Period = app.GlobalHandles.PeriodEditField.Value;
            app.WaveStruct.Frequency = 1/app.GlobalHandles.PeriodEditField.Value;
            app.GlobalHandles.FrequencyEditField.Value = 1/app.GlobalHandles.PeriodEditField.Value;
            PlotPreview(app)
        end
        
        % Function for the Frequency ValueChangedFcn callback
        function FrequencyValueChanged(app)
            app.WaveStruct.Frequency = app.GlobalHandles.FrequencyEditField.Value;
            app.WaveStruct.Period = 1/app.GlobalHandles.FrequencyEditField.Value;
            app.GlobalHandles.PeriodEditField.Value = 1/app.GlobalHandles.FrequencyEditField.Value;
            PlotPreview(app)
        end
        
        % Function for the Repeat ValueChangedFcn callback
        function RepeatValueChanged(app)
            app.WaveStruct.Repeat = app.GlobalHandles.RepeatCheckbox.Value;
            PlotPreview(app)
        end
        
        % Function for the HalfPulse ValueChangedFcn callback
        function HalfPulseValueChanged(app)
            app.WaveStruct.HalfPulse = app.GlobalHandles.HalfPulseCheckbox.Value;
            PlotPreview(app)
        end
        
        % Function for the Preview ButtonPushedFcn callback
        function PreviewButtonPushed(app)
            PlotPreview(app)
        end
        
        % Function for the Confirm ButtonPushedFcn callback
        function ConfirmButtonPushed(app)
            delete(app.GlobalHandles.SignalFigure)
            
            % Re-enable the WaveGenerator button
            app.SignalSettingsButton.Enable = 'on';
        end
        
        % Function for Plotting Waves
        function PlotPreview(app) 
            % Store inputs as properties
            TimeAxis = 0:1/app.WaveStruct.SampleRate:15;
            Repeat = app.WaveStruct.Repeat;
            HalfPulse = app.WaveStruct.HalfPulse;
            
            % Drawing Waves
            % Constant Pressure
            if strcmp(app.WaveStruct.WaveType, 'Constant')
                stem(app.GlobalHandles.PreviewAxes,TimeAxis,ones(size(TimeAxis))*app.WaveStruct.OffsetPressure)
            % Sine Wave
            elseif strcmp(app.WaveStruct.WaveType, 'Sine Wave')
                PressureValueFull = sin(TimeAxis*app.WaveStruct.Frequency*2*pi)*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                PressureValueHalf = abs(sin(TimeAxis*app.WaveStruct.Frequency*2*pi))*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                if Repeat
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                else
                    PressureValueFull(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    PressureValueHalf(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                end
            % Square Wave
            elseif strcmp(app.WaveStruct.WaveType, 'Square Wave')
                PressureValueFull = square(TimeAxis*app.WaveStruct.Frequency*2*pi)*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                PressureValueHalf = abs(square(TimeAxis*app.WaveStruct.Frequency*2*pi))*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                if Repeat
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                else
                    PressureValueFull(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    PressureValueHalf(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                end
            % Triangular Wave (Sawtooth)
            elseif strcmp(app.WaveStruct.WaveType, 'Triangular Wave')
                PressureValueFull = sawtooth(TimeAxis*app.WaveStruct.Frequency*2*pi,0.5)*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                PressureValueHalf = abs(sawtooth(TimeAxis*app.WaveStruct.Frequency*2*pi,0.5))*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                if Repeat
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                else
                    PressureValueFull(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    PressureValueHalf(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                end            
            % Positive Ramp Wave (slow rise fast drop)
            elseif strcmp(app.WaveStruct.WaveType, 'Ramp Wave Positive')
                PressureValueFull = sawtooth(TimeAxis*app.WaveStruct.Frequency*2*pi)*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                PressureValueHalf = abs(sawtooth(TimeAxis*app.WaveStruct.Frequency*2*pi))*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                if Repeat
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                else
                    PressureValueFull(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    PressureValueHalf(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                end
            % Negative Ramp Wave (fast rise slow drop)
            elseif strcmp(app.WaveStruct.WaveType, 'Ramp Wave Negative')
                PressureValueFull = sawtooth(TimeAxis*app.WaveStruct.Frequency*2*pi,0)*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                PressureValueHalf = abs(sawtooth(TimeAxis*app.WaveStruct.Frequency*2*pi,0))*app.WaveStruct.Amplitude+app.WaveStruct.OffsetPressure;
                if Repeat
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                else
                    PressureValueFull(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    PressureValueHalf(TimeAxis>app.WaveStruct.Period) = app.WaveStruct.OffsetPressure;
                    if HalfPulse
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueHalf)
                    else
                        stem(app.GlobalHandles.PreviewAxes,TimeAxis,PressureValueFull)
                    end
                end
            % Custom Built Wave
            elseif strcmp(app.WaveStruct.WaveType, 'Custom')
                stem(app.GlobalHandles.PreviewAxes,TimeAxis,ones(size(TimeAxis))*app.WaveStruct.OffsetPressure)
            else
                plot(app.GlobalHandles.PreviewAxes,[0 15],[1000 1000])
                hold(app.GlobalHandles.PreviewAxes, 'on');
                plot(app.GlobalHandles.PreviewAxes,[7.5 5.5],[0 1000])
                plot(app.GlobalHandles.PreviewAxes,[0 15],[0 0])
                hold(app.GlobalHandles.PreviewAxes, 'off');
            end
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SignalSettingsButton
        function SignalSettingsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SignalSettingsButton.Enable = 'off';
            
            % Create new UIFigure with input values
            SignalFigure = uifigure('Name','Signal Generator');
            app.GlobalHandles.SignalFigure = SignalFigure;
            
            % Axes Settings
            PreviewAxes = uiaxes(SignalFigure);
            PreviewAxes.Position = [5, 5, 350, 400];
            PreviewAxes.Title.String = 'Wave Preview';
            PreviewAxes.XLabel.String = 'Time (s)';
            PreviewAxes.YLabel.String = 'Pressure (hPa)';
            app.GlobalHandles.PreviewAxes = PreviewAxes;
            
            % Type Text Field
            TypeTextField = uilabel(SignalFigure);
            TypeTextField.Text = 'Wave Type';
            TypeTextField.Position = [360, 308, 60, 22];
            
            % Type Dropdown
            PopupTypeDropDown = uidropdown(SignalFigure);
            PopupTypeDropDown.Position = [430, 308, 110, 22];
            PopupTypeDropDown.Items = {'Constant','Sine Wave','Square Wave','Triangular Wave','Ramp Wave Positive','Ramp Wave Negative','Custom'};
            PopupTypeDropDown.Value = app.WaveStruct.WaveType;
            PopupTypeDropDown.ValueChangedFcn = {@(PopupTypeDropDown,event) PopupTypeDropDownChanged(app)};
            app.GlobalHandles.PopupTypeDropDown = PopupTypeDropDown;
            
            % Offset Pressure Text Field
            OffsetPressureTextField = uilabel(SignalFigure);
            OffsetPressureTextField.Text = 'Offset Pressure (hPa)';
            OffsetPressureTextField.Position = [360, 278, 120, 22];
            
            % Offset Pressure Edit Field
            OffsetPressureEditField = uieditfield(SignalFigure,'numeric');
            OffsetPressureEditField.Value = app.WaveStruct.OffsetPressure;
            OffsetPressureEditField.Position = [480, 278, 60, 22];
            OffsetPressureEditField.ValueChangedFcn = @(OffsetPressureEditField,event) OffsetPressureValueChanged(app);
            app.GlobalHandles.OffsetPressureEditField = OffsetPressureEditField;
            
            % Amplitude Text Field
            AmplitudeTextField = uilabel(SignalFigure);
            AmplitudeTextField.Text = 'Amplitude (hPa)';
            AmplitudeTextField.Position = [360, 248, 120, 22];
            
            % Amplitude Edit Field
            AmplitudeEditField = uieditfield(SignalFigure,'numeric');
            AmplitudeEditField.Value = app.WaveStruct.Amplitude;
            AmplitudeEditField.Position = [480, 248, 60, 22];
            AmplitudeEditField.ValueChangedFcn = @(AmplitudeEditField,event) AmplitudeValueChanged(app);
            app.GlobalHandles.AmplitudeEditField = AmplitudeEditField;
            
            % Period Text Field
            PeriodTextField = uilabel(SignalFigure);
            PeriodTextField.Text = 'Period (s)';
            PeriodTextField.Position = [360, 208, 120, 22];
            
            % Period Edit Field
            PeriodEditField = uieditfield(SignalFigure,'numeric');
            PeriodEditField.Value = app.WaveStruct.Period;
            PeriodEditField.Position = [480, 208, 60, 22];
            PeriodEditField.ValueChangedFcn = @(PeriodEditField,event) PeriodValueChanged(app);
            app.GlobalHandles.PeriodEditField = PeriodEditField;
            
            % Frequency Text Field
            FrequencyTextField = uilabel(SignalFigure);
            FrequencyTextField.Text = 'Frequency (Hz)';
            FrequencyTextField.Position = [360, 178, 120, 22];
            
            % Frequency Edit Field
            FrequencyEditField = uieditfield(SignalFigure,'numeric');
            FrequencyEditField.Value = 1/app.WaveStruct.Frequency;
            FrequencyEditField.Position = [480, 178, 60, 22];
            FrequencyEditField.ValueChangedFcn = @(FrequencyEditField,event) FrequencyValueChanged(app);
            app.GlobalHandles.FrequencyEditField = FrequencyEditField;
            
            % Repeat Checkbox
            RepeatCheckbox = uicheckbox(SignalFigure);
            RepeatCheckbox.Text = 'Repeat';
            RepeatCheckbox.Position = [410 138 100 22];
            RepeatCheckbox.ValueChangedFcn = @(RepeatCheckbox,event) RepeatValueChanged(app);
            app.GlobalHandles.RepeatCheckbox = RepeatCheckbox;
            
            % Half Pulse Checkbox
            HalfPulseCheckbox = uicheckbox(SignalFigure);
            HalfPulseCheckbox.Text = 'Half Pulse';
            HalfPulseCheckbox.Position = [410 108 100 22];
            HalfPulseCheckbox.ValueChangedFcn = @(HalfPulseCheckbox,event) HalfPulseValueChanged(app);
            app.GlobalHandles.HalfPulseCheckbox = HalfPulseCheckbox;
            
            % Preview Button to Preview Signal
            PreviewButton = uibutton(SignalFigure);
            PreviewButton.Text = 'Preview';
            PreviewButton.Position = [410, 78, 100, 22];
            PreviewButton.ButtonPushedFcn = {@(PreviewButton,event) PreviewButtonPushed(app)};
            app.GlobalHandles.PreviewButton = PreviewButton;
            
            % Confirm Button to Set Signal
            ConfirmButton = uibutton(SignalFigure);
            ConfirmButton.Text = 'Confirm';
            ConfirmButton.Position = [410, 48, 100, 22];
            ConfirmButton.ButtonPushedFcn = {@(ConfirmButton,event) ConfirmButtonPushed(app)};
            app.GlobalHandles.ConfirmButton = ConfirmButton;
            
            % Initial Plot
            PlotPreview(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete app
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {205, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SignalSettingsButton = uibutton(app.LeftPanel, 'push');
            app.SignalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SignalSettingsButtonPushed, true);
            app.SignalSettingsButton.Text = 'Signal Settings';
            app.TypeEditFieldLabel = uilabel(app.LeftPanel);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.LeftPanel, 'text');
            app.TypeEditField.Editable = 'off';
            app.TypeEditField.Value = 'Use Settings to Select Type';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Pressure Controller')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Pressure (hPa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end